classdef ElectricForceCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BtnSendNCharges       matlab.ui.control.Button
        fieldNumberCharges    matlab.ui.control.NumericEditField
        LblNoCargas           matlab.ui.control.Label
        lblNorma              matlab.ui.control.Label
        resultValue3          matlab.ui.control.Label
        resultValue2          matlab.ui.control.Label
        resultValue1          matlab.ui.control.Label
        LblResult             matlab.ui.control.Label
        KLabel_2              matlab.ui.control.Label
        JLabel_2              matlab.ui.control.Label
        iLabel_2              matlab.ui.control.Label
        KLabel                matlab.ui.control.Label
        JLabel                matlab.ui.control.Label
        iLabel                matlab.ui.control.Label
        position2k            matlab.ui.control.NumericEditField
        position1k            matlab.ui.control.NumericEditField
        position1j            matlab.ui.control.NumericEditField
        position2j            matlab.ui.control.NumericEditField
        position2i            matlab.ui.control.NumericEditField
        position2Label        matlab.ui.control.Label
        position1i            matlab.ui.control.NumericEditField
        positionC1Label       matlab.ui.control.Label
        Electriccharge2       matlab.ui.control.NumericEditField
        Electriccharge1Label  matlab.ui.control.Label
        Electriccharge1       matlab.ui.control.NumericEditField
        Electriccharge0Label  matlab.ui.control.Label
        CalculateButton       matlab.ui.control.Button
        Unit2                 matlab.ui.control.DropDown
        UnitDropDown_2Label   matlab.ui.control.Label
        Unit1                 matlab.ui.control.DropDown
        UnitDropDownLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

  
    methods (Access = public)
        
        function charge = unitcharge(c,u)
            if u == 'µC'
                qn=c*10.^-6;
            elseif u == 'nC'
                qn=c*10.^-10;
            else
                qn=c;
            end
            charge=qn;  
        end
        
        function norma = magnitude(v)
            norma = sqrt(sum(v.*v));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            n = app.fieldNumberCharges.Value;
            for i=1:n
                vectPos(i)=[app.position2i.Value,app.position2j.Value,app.position2k.Value];
                vectCar(i)=unitCharges(app.Unit2.Value);
            end
            
            %r1=[app.position1i.Value ,app.position1j.Value,app.position1k.Value];
            %r2=[app.position2i.Value,app.position2j.Value,app.position2k.Value];
            k=(9*10.^9);
            r12=r1-r2;
            n=magnitude(r12);
            sol=(k*q1*q2/n.^3)*r12;
            mag=magnitude(sol);
            %disp(mag);
            app.LblResult.Text = 'La fuerza electrica total ejercida sobre q0 es';
            app.resultValue1.Text = strcat(num2str(sol(1)),'N i +');
            app.resultValue2.Text = strcat(num2str(sol(2)),'N J +');
            app.resultValue3.Text = strcat(num2str(sol(3)),'N K ');
            app.lblNorma.Text = strcat('Y su magnitud es  = ',num2str(mag));
            %app.UIAxes.XAxisLocation="origin";
            %app.UIAxes.YAxisLocation="origin";
            quiver3(app.UIAxes,0,0,0,r1(1),r1(2),r1(3),'r');
            hold(app.UIAxes,'on')
            %quiver3(app.UIAxes,0,0,0,r1(1),r1(2),r1(3),'r');
            quiver3(app.UIAxes,0,0,0,r2(1),r2(2),r2(3),'r');
            quiver3(app.UIAxes,r2(1),r2(2),r2(3),r12(1),r12(2),r12(3),'r');
            text(app.UIAxes,r1(1),r1(2),r1(3),'  q0','Color','b');
            text(app.UIAxes,r2(1),r2(2),r2(3),'  q1','Color','b');
            plot3(app.UIAxes,r1(1),r1(2),r1(3),'b',Marker='o');
            plot3(app.UIAxes,r2(1),r2(2),r2(3),'b',Marker='o');
            hold(app.UIAxes,'off')
            %plot3(r2(1),r2(2),r2(3),'r','Marker','hexagram');
            %plot3(app.UIAxes,[0,2],[0,3],[0,4]);          
            %rectangle(app.UIAxes,'Position',[25,25,50,50]);           
            %app.normaValue.Text = strcat('h ',num2str(sol(1),'%1.4e'));

           
        end

        % Button pushed function: BtnSendNCharges
        function BtnSendNChargesButtonPushed(app, event)
            q0 = unitcharge(app.Electriccharge1.Value,app.Unit1.Value);
            r1=[app.position1i.Value ,app.position1j.Value,app.position1k.Value];
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hot');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'hot')
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.Unit1 = uidropdown(app.UIFigure);
            app.Unit1.Items = {'C', 'µC', 'nC'};
            app.Unit1.Value = 'C';
            app.UnitDropDown_2Label = uilabel(app.UIFigure);
            app.UnitDropDown_2Label.Text = 'Unit ';
            app.Unit2 = uidropdown(app.UIFigure);
            app.Unit2.Items = {'C', 'µC', 'nC'};
            app.Unit2.Value = 'C';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Electriccharge0Label = uilabel(app.UIFigure);
            app.Electriccharge0Label.Text = 'Electric charge 0';
            app.Electriccharge1 = uieditfield(app.UIFigure, 'numeric');
            app.Electriccharge1Label = uilabel(app.UIFigure);
            app.Electriccharge1Label.Text = 'Electric charge 1';
            app.Electriccharge2 = uieditfield(app.UIFigure, 'numeric');
            app.positionC1Label = uilabel(app.UIFigure);
            app.positionC1Label.Text = 'position C0';
            app.position1i = uieditfield(app.UIFigure, 'numeric');
            app.position2Label = uilabel(app.UIFigure);
            app.position2Label.Text = 'position C1';
            app.position2i = uieditfield(app.UIFigure, 'numeric');
            app.position2j = uieditfield(app.UIFigure, 'numeric');
            app.position1j = uieditfield(app.UIFigure, 'numeric');
            app.position1k = uieditfield(app.UIFigure, 'numeric');
            app.position2k = uieditfield(app.UIFigure, 'numeric');
            app.iLabel = uilabel(app.UIFigure);
            app.iLabel.Text = ' i';
            app.JLabel = uilabel(app.UIFigure);
            app.JLabel.Text = ' J';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = ' K';
            app.iLabel_2 = uilabel(app.UIFigure);
            app.iLabel_2.Text = '  i';
            app.JLabel_2 = uilabel(app.UIFigure);
            app.JLabel_2.Text = ' J';
            app.KLabel_2 = uilabel(app.UIFigure);
            app.KLabel_2.Text = ' K';
            app.LblResult = uilabel(app.UIFigure);
            app.LblResult.Text = '';
            app.resultValue1 = uilabel(app.UIFigure);
            app.resultValue1.Text = '';
            app.resultValue2 = uilabel(app.UIFigure);
            app.resultValue2.Text = '';
            app.resultValue3 = uilabel(app.UIFigure);
            app.resultValue3.Text = '';
            app.lblNorma = uilabel(app.UIFigure);
            app.lblNorma.Text = '';
            app.LblNoCargas = uilabel(app.UIFigure);
            app.LblNoCargas.Text = 'Number of charges';
            app.fieldNumberCharges = uieditfield(app.UIFigure, 'numeric');
            app.fieldNumberCharges.Value = 1;
            app.BtnSendNCharges = uibutton(app.UIFigure, 'push');
            app.BtnSendNCharges.ButtonPushedFcn = createCallbackFcn(app, @BtnSendNChargesButtonPushed, true);
            app.BtnSendNCharges.Text = 'load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectricForceCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end